classdef CourseworkGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        IntersectingshapecolourButtonGroup  matlab.ui.container.ButtonGroup
        intsectr                       matlab.ui.control.RadioButton
        intsectg                       matlab.ui.control.RadioButton
        intsectk                       matlab.ui.control.RadioButton
        intsectb                       matlab.ui.control.RadioButton
        intsectm                       matlab.ui.control.RadioButton
        HowmanyshapesSpinner           matlab.ui.control.Spinner
        HowmanyshapesSpinnerLabel      matlab.ui.control.Label
        SidelengthingraphlineSpinner   matlab.ui.control.Spinner
        SidelengthingraphlineSpinnerLabel  matlab.ui.control.Label
        PlotButton                     matlab.ui.control.Button
        WhatwouldliketoestimateDropDownLabel  matlab.ui.control.Label
        WhatwouldliketoestimateDropDown  matlab.ui.control.DropDown
        HowmanysidesDropDownLabel      matlab.ui.control.Label
        HowmanysidesDropDown           matlab.ui.control.DropDown
        NumberofcracksSliderLabel      matlab.ui.control.Label
        NumberofcracksSlider           matlab.ui.control.Slider
        UnintersectingshapecolourButtonGroup  matlab.ui.container.ButtonGroup
        unintr                         matlab.ui.control.RadioButton
        unintg                         matlab.ui.control.RadioButton
        unintb                         matlab.ui.control.RadioButton
        unintk                         matlab.ui.control.RadioButton
        unintm                         matlab.ui.control.RadioButton
        UncertaintyLampLabel           matlab.ui.control.Label
        UncertaintyLamp                matlab.ui.control.Lamp
        AreCrackssquareCheckBox        matlab.ui.control.CheckBox
        ExplanationPerfectpiis31416Perfectsurd2is14142Label  matlab.ui.control.Label
        ClearPlotButton                matlab.ui.control.Button
        CrackcolourButtonGroup         matlab.ui.container.ButtonGroup
        crackr                         matlab.ui.control.RadioButton
        crackg                         matlab.ui.control.RadioButton
        crackb                         matlab.ui.control.RadioButton
        crackk                         matlab.ui.control.RadioButton
        crackm                         matlab.ui.control.RadioButton
        EnlargeValueTextCheckBox       matlab.ui.control.CheckBox
        ValueTexttoCyanCheckBox        matlab.ui.control.CheckBox
        EnableneedleselectionCheckBox  matlab.ui.control.CheckBox
        OrientationSpinnerLabel        matlab.ui.control.Label
        OrientationSpinner             matlab.ui.control.Spinner
        Find3closestneedletoclickCheckBox  matlab.ui.control.CheckBox
        UIAxes                         matlab.ui.control.UIAxes
        ContextMenu                    matlab.ui.container.ContextMenu
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
           sidelength=app.SidelengthingraphlineSpinner.Value;
           crackdraw=1/app.NumberofcracksSlider.Value;
           numofshape=app.HowmanyshapesSpinner.Value;
           sidenum=app.HowmanysidesDropDown.Value;
           app.UncertaintyLamp.Color = 'black'; %Black default lamp
           isquarefloor=app.AreCrackssquareCheckBox.Value;
           
           if (app.crackk.Value)
               crackcolor='k';
           elseif (app.crackb.Value)
               crackcolor='b';
           elseif (app.crackg.Value)
               crackcolor='g';
           elseif (app.crackr.Value)
               crackcolor='r';
           elseif (app.crackm.Value)
               crackcolor='m';
           end
           
           if (app.intsectk.Value)
               intcolor='k';
           elseif (app.intsectb.Value)
               intcolor='b';
           elseif (app.intsectg.Value)
               intcolor='g';
           elseif (app.intsectr.Value)
               intcolor='r';
           elseif (app.intsectm.Value)
               intcolor='m';
           end
           
           if (app.unintk.Value)
               unintcolor='k';
           elseif (app.unintb.Value)
               unintcolor='b';
           elseif (app.unintg.Value)
               unintcolor='g';
           elseif (app.unintr.Value)
               unintcolor='r';
           elseif (app.unintm.Value)
               unintcolor='m';
           end
           
           firsty=sidelength + rand(1,numofshape)*(1-2*sidelength);
           firstx=sidelength + rand(1,numofshape)*(1-2*sidelength);
           tiltangle=360*rand(1,numofshape);
           secy=firsty+sidelength*cosd(tiltangle);
           secx=firstx+sidelength*sind(tiltangle);
           cdplce=1/crackdraw;
           
           if sidenum ==2
              %% These extra for code consistency
              thiy=firsty;
              thix=firstx;
              %%
              if isquarefloor==1
                 scored=sum((floor(secx/sidelength) ~= floor(thix/sidelength)))+sum(floor(secy/sidelength) ~= sum(thiy/sidelength));
                 totallnum=2*numofshape;
              else
                 scored=sum((floor(secx/sidelength) ~= floor(thix/sidelength)));
                 totallnum=numofshape; %for consistency
              end

           elseif sidenum == 3
               thiy=secy+sidelength*cosd(tiltangle+120);
               thix=secx+sidelength*sind(tiltangle+120);
               fouy=thiy+sidelength*cosd(tiltangle+240);
               foux=thix+sidelength*sind(tiltangle+240);
               fify=fouy+sidelength*cosd(tiltangle+360);
               fifx=foux+sidelength*sind(tiltangle+360);
               if isquarefloor==1
                   scored=sum((floor(secx/sidelength) ~= floor(thix/sidelength)))+sum((floor(thix/sidelength) ~= floor(foux/sidelength)))+sum((floor(foux/sidelength) ~= floor(fifx/sidelength)))+sum((floor(secy/sidelength) ~= floor(thiy/sidelength)))+sum((floor(thiy/sidelength) ~= floor(fouy/sidelength)))+sum((floor(fouy/sidelength) ~= floor(fify/sidelength)));
                   totallnum=3*2*numofshape;
               else
                   scored=sum((floor(secx/sidelength) ~= floor(thix/sidelength)))+sum((floor(thix/sidelength) ~= floor(foux/sidelength)))+sum((floor(foux/sidelength) ~= floor(fifx/sidelength)));
                   totallnum=3*numofshape;
               end
               
           elseif sidenum == 4
               thiy=secy+sidelength*cosd(tiltangle+90);
               thix=secx+sidelength*sind(tiltangle+90);
               fouy=thiy+sidelength*cosd(tiltangle+180);
               foux=thix+sidelength*sind(tiltangle+180);
               fify=fouy+sidelength*cosd(tiltangle+270);
               fifx=foux+sidelength*sind(tiltangle+270);
               sixy=fify+sidelength*cosd(tiltangle+360);
               sixx=fifx+sidelength*sind(tiltangle+360);
               if isquarefloor==1 %if on square hole floor
                    scored=sum((floor(secx/sidelength) ~= floor(thix/sidelength)))+sum((floor(thix/sidelength) ~= floor(foux/sidelength)))+sum((floor(foux/sidelength) ~= floor(fifx/sidelength)))+sum((floor(fifx/sidelength) ~= floor(sixx/sidelength)))+sum((floor(secy/sidelength) ~= floor(thiy/sidelength)))+sum((floor(thiy/sidelength) ~= floor(fouy/sidelength)))+sum((floor(fouy/sidelength) ~= floor(fify/sidelength)))+sum((floor(fify/sidelength) ~= floor(sixy/sidelength)));
                    totallnum=4*2*numofshape;
               else %if on line hole floor
                    scored=sum((floor(secx/sidelength) ~= floor(thix/sidelength)))+sum((floor(thix/sidelength) ~= floor(foux/sidelength)))+sum((floor(foux/sidelength) ~= floor(fifx/sidelength)))+sum((floor(fifx/sidelength) ~= floor(sixx/sidelength)));
                    totallnum=4*numofshape;
               end
               
           elseif sidenum == 5
               thiy=secy+sidelength*cosd(tiltangle+72);
               thix=secx+sidelength*sind(tiltangle+72);
               fouy=thiy+sidelength*cosd(tiltangle+144);
               foux=thix+sidelength*sind(tiltangle+144);
               fify=fouy+sidelength*cosd(tiltangle+216);
               fifx=foux+sidelength*sind(tiltangle+216);
               sixy=fify+sidelength*cosd(tiltangle+288);
               sixx=fifx+sidelength*sind(tiltangle+288);
               sevy=sixy+sidelength*cosd(tiltangle+360);
               sevx=sixx+sidelength*sind(tiltangle+360);
               if isquarefloor==1
                    scored=sum((floor(secx/sidelength) ~= floor(thix/sidelength)))+sum((floor(thix/sidelength) ~= floor(foux/sidelength)))+sum((floor(foux/sidelength) ~= floor(fifx/sidelength)))+sum((floor(fifx/sidelength) ~= floor(sixx/sidelength)))+sum(floor(sixx/sidelength) ~= sum(floor(sevx/sidelength)))+sum((floor(secy/sidelength) ~= floor(thiy/sidelength)))+sum((floor(thiy/sidelength) ~= floor(fouy/sidelength)))+sum((floor(fouy/sidelength) ~= floor(fify/sidelength)))+sum((floor(fify/sidelength) ~= floor(sixy/sidelength)))+sum(floor(sixy/sidelength) ~= floor(seventh/sidelength));
                    totallnum=5*2*numofshape;
               else
                    scored=sum((floor(secx/sidelength) ~= floor(thix/sidelength)))+sum((floor(thix/sidelength) ~= floor(foux/sidelength)))+sum((floor(foux/sidelength) ~= floor(fifx/sidelength)))+sum((floor(fifx/sidelength) ~= floor(sixx/sidelength)))+sum(floor(sixx/sidelength) ~= sum(floor(sevx/sidelength)));
                    totallnum=5*numofshape;
               end

           end
           
           axis square
           hold(app.UIAxes, 'on')
           crack=0:crackdraw:1;
           
           for ii = 1:length(crack)
               xline(app.UIAxes, crack(ii), 'Color', crackcolor);
               if isquarefloor==1
                   yline(app.UIAxes, crack(ii), 'Color', crackcolor);
               end
           end
           
           for jj=1:length(secx)
               secxisec(jj)=(floor(secx(jj)/sidelength) ~= floor(thix(jj)/sidelength));
               if sidenum==2
                   if isquarefloor==1
                     secyisec(jj)=(floor(secy(jj)/sidelength) ~= floor(thiy(jj)/sidelength));
                     if (secxisec(jj)==0) && (secyisec(jj)==0)
                        plot(app.UIAxes, [secx(jj);thix(jj)], [secy(jj);thiy(jj)], 'Color',unintcolor);
                        hold(app.UIAxes, 'on')
                     else
                        plot(app.UIAxes, [secx(jj);thix(jj)], [secy(jj);thiy(jj)], 'Color',intcolor);
                        hold(app.UIAxes, 'on')
                     end
                   else
                     if secxisec(jj)==0
                        plot(app.UIAxes, [secx(jj);thix(jj)], [secy(jj);thiy(jj)], 'Color',unintcolor);
                        hold(app.UIAxes, 'on')
                     else
                        plot(app.UIAxes, [secx(jj);thix(jj)], [secy(jj);thiy(jj)], 'Color',intcolor);
                        hold(app.UIAxes, 'on')
                     end
                   end
               elseif sidenum==3
                   thixisec(jj)=(floor(thix(jj)/sidelength) ~= floor(foux(jj)/sidelength));
                   fouxisec(jj)=(floor(foux(jj)/sidelength) ~= floor(fifx(jj)/sidelength));
                   if isquarefloor==1
                     secyisec(jj)=(floor(secy(jj)/sidelength) ~= floor(thiy(jj)/sidelength));
                     thiyisec(jj)=(floor(thiy(jj)/sidelength) ~= floor(fouy(jj)/sidelength));
                     fouyisec(jj)=(floor(fouy(jj)/sidelength) ~= floor(fify(jj)/sidelength));
                     if (secxisec(jj)==0) && (thixisec(jj)==0) && (fouxisec(jj)==0) && (secyisec(jj)==0) && (thiyisec(jj)==0) && (fouyisec(jj)==0)
                        plot(app.UIAxes, [secx(jj);thix(jj);foux(jj);secx(jj)], [secy(jj);thiy(jj);fouy(jj);secy(jj)], 'Color',unintcolor);
                        hold(app.UIAxes, 'on')
                     else
                        plot(app.UIAxes, [secx(jj);thix(jj);foux(jj);secx(jj)], [secy(jj);thiy(jj);fouy(jj);secy(jj)], 'Color',intcolor);
                        hold(app.UIAxes, 'on')
                     end
                   else
                     if (secxisec(jj)==0) && (thixisec(jj)==0) && (fouxisec(jj)==0)
                        plot(app.UIAxes, [secx(jj);thix(jj);foux(jj);secx(jj)], [secy(jj);thiy(jj);fouy(jj);secy(jj)], 'Color',unintcolor);
                        hold(app.UIAxes, 'on')
                     else
                        plot(app.UIAxes, [secx(jj);thix(jj);foux(jj);secx(jj)], [secy(jj);thiy(jj);fouy(jj);secy(jj)], 'Color',intcolor);
                        hold(app.UIAxes, 'on')
                     end
                   end
                elseif sidenum==4
                   thixisec(jj)=(floor(thix(jj)/sidelength) ~= floor(foux(jj)/sidelength));
                   fouxisec(jj)=(floor(foux(jj)/sidelength) ~= floor(fifx(jj)/sidelength));
                   fifxisec(jj)=(floor(fifx(jj)/sidelength) ~= floor(sixx(jj)/sidelength));
                   if isquarefloor==1
                     secyisec(jj)=(floor(secy(jj)/sidelength) ~= floor(thiy(jj)/sidelength));
                     thiyisec(jj)=(floor(thiy(jj)/sidelength) ~= floor(fouy(jj)/sidelength));
                     fouyisec(jj)=(floor(fouy(jj)/sidelength) ~= floor(fify(jj)/sidelength));
                     fifyisec(jj)=(floor(fify(jj)/sidelength) ~= floor(sixy(jj)/sidelength));
                     if (secxisec(jj)==0) && (thixisec(jj)==0) && (fouxisec(jj)==0) && (fifxisec(jj)==0) && (secyisec(jj)==0) && (thiyisec(jj)==0) && (fouyisec(jj)==0) && (fifyisec(jj)==0)
                        plot(app.UIAxes, [secx(jj);thix(jj);foux(jj);fifx(jj);secx(jj)], [secy(jj);thiy(jj);fouy(jj);fify(jj);secy(jj)], 'Color',unintcolor);
                        hold(app.UIAxes, 'on')
                     else
                        plot(app.UIAxes, [secx(jj);thix(jj);foux(jj);fifx(jj);secx(jj)], [secy(jj);thiy(jj);fouy(jj);fify(jj);secy(jj)], 'Color',intcolor);
                        hold(app.UIAxes, 'on')
                     end
                   else
                     if (secxisec(jj)==0) && (thixisec(jj)==0) && (fouxisec(jj)==0) && (fifxisec(jj)==0)
                        plot(app.UIAxes, [secx(jj);thix(jj);foux(jj);fifx(jj);secx(jj)], [secy(jj);thiy(jj);fouy(jj);fify(jj);secy(jj)], 'Color',unintcolor);
                        hold(app.UIAxes, 'on')
                     else
                        plot(app.UIAxes, [secx(jj);thix(jj);foux(jj);fifx(jj);secx(jj)], [secy(jj);thiy(jj);fouy(jj);fify(jj);secy(jj)], 'Color',intcolor);
                        hold(app.UIAxes, 'on')
                     end
                   end
                 elseif sidenum==5
                   thixisec(jj)=(floor(thix(jj)/sidelength) ~= floor(foux(jj)/sidelength));
                   fouxisec(jj)=(floor(foux(jj)/sidelength) ~= floor(fifx(jj)/sidelength));
                   fifxisec(jj)=(floor(fifx(jj)/sidelength) ~= floor(sixx(jj)/sidelength));
                   sixxisec(jj)=(floor(sixx(jj)/sidelength) ~= floor(sevx(jj)/sidelength));
                   if isquarefloor==1
                     secyisec(jj)=(floor(secy(jj)/sidelength) ~= floor(thiy(jj)/sidelength));
                     thiyisec(jj)=(floor(thiy(jj)/sidelength) ~= floor(fouy(jj)/sidelength));
                     fouyisec(jj)=(floor(fouy(jj)/sidelength) ~= floor(fify(jj)/sidelength));
                     fifyisec(jj)=(floor(fify(jj)/sidelength) ~= floor(sixy(jj)/sidelength));
                     sixyisec(jj)=(floor(sixy(jj)/sidelength) ~= floor(sevy(jj)/sidelength));
                     if (secxisec(jj)==0) && (thixisec(jj)==0) && (fouxisec(jj)==0) && (fifxisec(jj)==0) && (sixxisec(jj)==0) && (secyisec(jj)==0) && (thiyisec(jj)==0) && (fouyisec(jj)==0) && (fifyisec(jj)==0) && (sixjisec(jj)==0)
                        plot(app.UIAxes, [secx(jj);thix(jj);foux(jj);fifx(jj);sixx(jj);secx(jj)], [secy(jj);thiy(jj);fouy(jj);fify(jj);sixy(jj);secy(jj)], 'Color',unintcolor);
                        hold(app.UIAxes, 'on')
                     else
                        plot(app.UIAxes, [secx(jj);thix(jj);foux(jj);fifx(jj);sixx(jj);secx(jj)], [secy(jj);thiy(jj);fouy(jj);fify(jj);sixy(jj);secy(jj)], 'Color',intcolor);
                        hold(app.UIAxes, 'on')
                     end
                   else
                     if (secxisec(jj)==0) && (thixisec(jj)==0) && (fouxisec(jj)==0) && (fifxisec(jj)==0) && (sixxisec(jj)==0)
                        plot(app.UIAxes, [secx(jj);thix(jj);foux(jj);fifx(jj);sixx(jj);secx(jj)], [secy(jj);thiy(jj);fouy(jj);fify(jj);sixy(jj);secy(jj)], 'Color',unintcolor);
                        hold(app.UIAxes, 'on')
                     else
                        plot(app.UIAxes, [secx(jj);thix(jj);foux(jj);fifx(jj);sixx(jj);secx(jj)], [secy(jj);thiy(jj);fouy(jj);fify(jj);sixy(jj);secy(jj)], 'Color',intcolor);
                        hold(app.UIAxes, 'on')
                     end
                   end
               end
           end
           
           esti=app.WhatwouldliketoestimateDropDown.Value;
           aboutresult=2*totallnum/scored;
           if esti=="Pi"
               perfectresult=pi;
               app.UIAxes.Title.String = "Estimate of \pi is " + aboutresult;
           elseif esti=="Sqrt(2)"
               perfectresult=sqrt(2);
               aboutresult=1/sin(aboutresult/4);
               app.UIAxes.Title.String = "Estimate of \surd 2 is " + aboutresult;
           
           end
           
           if abs((aboutresult-perfectresult)/perfectresult)<=0.05 %Novel extension 1: uncertainty lamp by decimal value
                app.UncertaintyLamp.Color = 'g'; %Green lamp if the difference between the accepted result and the calculated result is less than 0.4 inclusive
                app.UncertaintyLampLabel.Text = 'Very close to ' + string(perfectresult);
           elseif abs((aboutresult-perfectresult)/perfectresult)<=0.2
                app.UncertaintyLamp.Color = [1, 0.41, 0.16]; %Orange lamp if the difference between the accepted result and the calculated result is between 0.4 exclusive to 1 inclusive
                app.UncertaintyLampLabel.Text = 'Getting far from ' + string(perfectresult);
           else
                app.UncertaintyLamp.Color = 'r';
                app.UncertaintyLampLabel.Text = 'Too far from ' + string(perfectresult);
           end %Red lamp otherwise
            %plot(app.UIAxes,)
        end

        % Value changing function: SidelengthingraphlineSpinner
        function SidelengthingraphlineSpinnerValueChanging(app, event)

            
        end

        % Value changing function: NumberofcracksSlider
        function NumberofcracksSliderValueChanging(app, event)

            
        end

        % Value changed function: WhatwouldliketoestimateDropDown
        function WhatwouldliketoestimateDropDownValueChanged(app, event)

            
        end

        % Selection changed function: 
        % UnintersectingshapecolourButtonGroup
        function UnintersectingshapecolourButtonGroupSelectionChanged(app, event)

            
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            cla(app.UIAxes);
            app.UIAxes.Title.String="What would you like to estimate now?";
            app.UncertaintyLampLabel.Text="Uncertainty Lamp";
            app.UncertaintyLamp.Color = 'k';
        end

        % Selection changed function: CrackcolourButtonGroup
        function CrackcolourButtonGroupSelectionChanged(app, event)

            
        end

        % Selection changed function: 
        % IntersectingshapecolourButtonGroup
        function IntersectingshapecolourButtonGroupSelectionChanged(app, event)

            
        end

        % Value changed function: EnlargeValueTextCheckBox
        function EnlargeValueTextCheckBoxValueChanged(app, event)
            value = app.EnlargeValueTextCheckBox.Value;
            if value ==1
                app.UIAxes.Title.FontSize = 19;
            else
                app.UIAxes.Title.FontSize = 12;
            end
        end

        % Value changed function: ValueTexttoCyanCheckBox
        function ValueTexttoCyanCheckBoxValueChanged(app, event)
            value2 = app.ValueTexttoCyanCheckBox.Value;
            if value2==1
                app.UIAxes.Title.Color = 'c';
            else
                app.UIAxes.Title.Color = 'k';
            end
        end

        % Value changed function: EnableneedleselectionCheckBox
        function EnableneedleselectionCheckBoxValueChanged(app, event)
            nsel = app.EnableneedleselectionCheckBox.Value;
            if nsel==1
                [gx,gy]=ginput(2);
                h = gco();
                hx=h.XData;
                hy=h.YData;
                hc=h.CData;
                near= sqrt((gx-hx).^2 + (gy-hy).^2);
                [~, minIdx]= min(near);
                plot(app.UIAxes, [gx;hx], [gy;hy], 'Color', 'y');
                
            end
        end

        % Value changed function: HowmanysidesDropDown
        function HowmanysidesDropDownValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IntersectingshapecolourButtonGroup = uibuttongroup(app.UIFigure);
            app.IntersectingshapecolourButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @IntersectingshapecolourButtonGroupSelectionChanged, true);
            app.IntersectingshapecolourButtonGroup.Title = 'Intersecting shape colour';
            app.intsectr = uiradiobutton(app.IntersectingshapecolourButtonGroup);
            app.intsectr.Tag = 'rintsect';
            app.intsectr.Text = 'Red';
            app.intsectg = uiradiobutton(app.IntersectingshapecolourButtonGroup);
            app.intsectg.Tag = 'gintsect';
            app.intsectg.Text = 'Green';
            app.intsectg.Value = true;
            app.intsectk = uiradiobutton(app.IntersectingshapecolourButtonGroup);
            app.intsectk.Tag = 'kintsect';
            app.intsectk.Text = 'Black';
            app.intsectb = uiradiobutton(app.IntersectingshapecolourButtonGroup);
            app.intsectb.Tag = 'bintsect';
            app.intsectb.Text = 'Blue';
            app.intsectm = uiradiobutton(app.IntersectingshapecolourButtonGroup);
            app.intsectm.Tag = 'kunintsect';
            app.intsectm.Text = 'Magenta';
            app.HowmanyshapesSpinner = uispinner(app.UIFigure);
            app.HowmanyshapesSpinner.Limits = [1 2000000];
            app.HowmanyshapesSpinner.Value = 10;
            app.HowmanyshapesSpinnerLabel = uilabel(app.UIFigure);
            app.HowmanyshapesSpinnerLabel.Text = 'How many shapes?';
            app.SidelengthingraphlineSpinner = uispinner(app.UIFigure);
            app.SidelengthingraphlineSpinner.Step = 0.01;
            app.SidelengthingraphlineSpinner.ValueChangingFcn = createCallbackFcn(app, @SidelengthingraphlineSpinnerValueChanging, true);
            app.SidelengthingraphlineSpinner.Limits = [0.01 1];
            app.SidelengthingraphlineSpinner.Value = 0.05;
            app.SidelengthingraphlineSpinnerLabel = uilabel(app.UIFigure);
            app.SidelengthingraphlineSpinnerLabel.Text = 'Side length in graph line';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.WhatwouldliketoestimateDropDownLabel = uilabel(app.UIFigure);
            app.WhatwouldliketoestimateDropDownLabel.Text = 'What would like to estimate?';
            app.WhatwouldliketoestimateDropDown = uidropdown(app.UIFigure);
            app.WhatwouldliketoestimateDropDown.Items = {'Pi', 'Sqrt(2)'};
            app.WhatwouldliketoestimateDropDown.ItemsData = {'Pi', 'Sqrt(2)'};
            app.WhatwouldliketoestimateDropDown.ValueChangedFcn = createCallbackFcn(app, @WhatwouldliketoestimateDropDownValueChanged, true);
            app.WhatwouldliketoestimateDropDown.Tag = 'esti';
            app.WhatwouldliketoestimateDropDown.Value = 'Pi';
            app.HowmanysidesDropDownLabel = uilabel(app.UIFigure);
            app.HowmanysidesDropDownLabel.Text = 'How many sides?';
            app.HowmanysidesDropDown = uidropdown(app.UIFigure);
            app.HowmanysidesDropDown.Items = {'2', '3', '4', '5'};
            app.HowmanysidesDropDown.ItemsData = [2 3 4 5];
            app.HowmanysidesDropDown.ValueChangedFcn = createCallbackFcn(app, @HowmanysidesDropDownValueChanged, true);
            app.HowmanysidesDropDown.Value = 4;
            app.NumberofcracksSliderLabel = uilabel(app.UIFigure);
            app.NumberofcracksSliderLabel.Text = 'Number of cracks';
            app.NumberofcracksSlider = uislider(app.UIFigure);
            app.NumberofcracksSlider.Limits = [10 50];
            app.NumberofcracksSlider.ValueChangingFcn = createCallbackFcn(app, @NumberofcracksSliderValueChanging, true);
            app.NumberofcracksSlider.Value = 20;
            app.UnintersectingshapecolourButtonGroup = uibuttongroup(app.UIFigure);
            app.UnintersectingshapecolourButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UnintersectingshapecolourButtonGroupSelectionChanged, true);
            app.UnintersectingshapecolourButtonGroup.Title = 'Unintersecting shape colour';
            app.unintr = uiradiobutton(app.UnintersectingshapecolourButtonGroup);
            app.unintr.Tag = 'rintsect';
            app.unintr.Text = 'Red';
            app.unintg = uiradiobutton(app.UnintersectingshapecolourButtonGroup);
            app.unintg.Tag = 'gintsect';
            app.unintg.Text = 'Green';
            app.unintb = uiradiobutton(app.UnintersectingshapecolourButtonGroup);
            app.unintb.Tag = 'bintsect';
            app.unintb.Text = 'Blue';
            app.unintb.Value = true;
            app.unintk = uiradiobutton(app.UnintersectingshapecolourButtonGroup);
            app.unintk.Tag = 'kunintsect';
            app.unintk.Text = 'Black';
            app.unintm = uiradiobutton(app.UnintersectingshapecolourButtonGroup);
            app.unintm.Tag = 'kunintsect';
            app.unintm.Text = 'Magenta';
            app.UncertaintyLampLabel = uilabel(app.UIFigure);
            app.UncertaintyLampLabel.Text = 'Uncertainty Lamp';
            app.UncertaintyLamp = uilamp(app.UIFigure);
            app.AreCrackssquareCheckBox = uicheckbox(app.UIFigure);
            app.AreCrackssquareCheckBox.Text = 'Are Cracks square?';
            app.ExplanationPerfectpiis31416Perfectsurd2is14142Label = uilabel(app.UIFigure);
            app.ExplanationPerfectpiis31416Perfectsurd2is14142Label.Text = {'Explanation:'; 'Perfect pi is 3.1416'; 'Perfect √2 is 1.4142'; ''; 'Make Sure You Click ''Clear Plot'' if you had already plotted'};
            app.ClearPlotButton = uibutton(app.UIFigure, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            app.CrackcolourButtonGroup = uibuttongroup(app.UIFigure);
            app.CrackcolourButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CrackcolourButtonGroupSelectionChanged, true);
            app.CrackcolourButtonGroup.Title = 'Crack colour';
            app.crackr = uiradiobutton(app.CrackcolourButtonGroup);
            app.crackr.Tag = 'rcrack';
            app.crackr.Text = 'Red';
            app.crackr.Value = true;
            app.crackg = uiradiobutton(app.CrackcolourButtonGroup);
            app.crackg.Tag = 'gcrack';
            app.crackg.Text = 'Green';
            app.crackb = uiradiobutton(app.CrackcolourButtonGroup);
            app.crackb.Tag = 'bcrack';
            app.crackb.Text = 'Blue';
            app.crackk = uiradiobutton(app.CrackcolourButtonGroup);
            app.crackk.Tag = 'kcrack';
            app.crackk.Text = 'Black';
            app.crackm = uiradiobutton(app.CrackcolourButtonGroup);
            app.crackm.Tag = 'kunintsect';
            app.crackm.Text = 'Magenta';
            app.EnlargeValueTextCheckBox = uicheckbox(app.UIFigure);
            app.EnlargeValueTextCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnlargeValueTextCheckBoxValueChanged, true);
            app.EnlargeValueTextCheckBox.Text = 'Enlarge Value Text';
            app.ValueTexttoCyanCheckBox = uicheckbox(app.UIFigure);
            app.ValueTexttoCyanCheckBox.ValueChangedFcn = createCallbackFcn(app, @ValueTexttoCyanCheckBoxValueChanged, true);
            app.ValueTexttoCyanCheckBox.Text = 'Value Text to Cyan';
            app.EnableneedleselectionCheckBox = uicheckbox(app.UIFigure);
            app.EnableneedleselectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableneedleselectionCheckBoxValueChanged, true);
            app.EnableneedleselectionCheckBox.Tag = 'nsel';
            app.EnableneedleselectionCheckBox.Text = 'Enable needle selection';
            app.OrientationSpinnerLabel = uilabel(app.UIFigure);
            app.OrientationSpinnerLabel.Text = 'Orientation';
            app.OrientationSpinner = uispinner(app.UIFigure);
            app.OrientationSpinner.Limits = [1 Inf];
            app.OrientationSpinner.Tag = 'ori';
            app.OrientationSpinner.Editable = 'off';
            app.OrientationSpinner.Value = 3;
            app.Find3closestneedletoclickCheckBox = uicheckbox(app.UIFigure);
            app.Find3closestneedletoclickCheckBox.Tag = 'closn';
            app.Find3closestneedletoclickCheckBox.Text = 'Find 3 closest needle to click';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'The Estimate of')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.XTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CourseworkGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end